classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AbsoluteValueCheckBox          matlab.ui.control.CheckBox
        TimeScopeOptionsLabel          matlab.ui.control.Label
        SineWaveComplexLabel           matlab.ui.control.Label
        SineWaveComplexCheckBox        matlab.ui.control.CheckBox
        DataOptionsLabel               matlab.ui.control.Label
        SineWaveFrequency              matlab.ui.control.NumericEditField
        SineWaveFrequencyHzLabel       matlab.ui.control.Label
        TimeScopeCheckBox              matlab.ui.control.CheckBox
        TransmittingLabel              matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        ChooseTransmitTypeButtonGroup  matlab.ui.container.ButtonGroup
        HexStringButton                matlab.ui.control.RadioButton
        CommandButton                  matlab.ui.control.RadioButton
        CommandDropDown                matlab.ui.control.DropDown
        CommandLabel                   matlab.ui.control.Label
        ASKDropDown                    matlab.ui.control.DropDown
        ASKDropDownLabel               matlab.ui.control.Label
        InvalidHexLabel                matlab.ui.control.Label
        SampleRateField                matlab.ui.control.NumericEditField
        EnterSampleRateinMHzEditFieldLabel  matlab.ui.control.Label
        HexStringField                 matlab.ui.control.EditField
        EnterHexStringLabel            matlab.ui.control.Label
        TransmitButton                 matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function usrpTransmit(app, hexString)
            clc; %clears the console

            %%Get initial values
            fs = app.SampleRateField.Value * 10^6;
            InterpolationFactor = round(100e+06/fs);
            ask_percent = app.ASKDropDown.Value;
            time_scope = timescope(SampleRate=fs); %create time scope object
            
            %%Modulates data
            [modData, ~, eof] = modulateHexString(hexString, fs, ask_percent); %
            slot = generateSlotData(fs);
            modData = modData';
            data = [slot;modData;slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot;eof';slot;eof';...
                    slot];
            sine_data = generateSine(fs, length(data), app.SineWaveFrequency.Value, app.SineWaveComplexCheckBox.Value);
            data = data.*sine_data;

            %%Plots data if check box is selected
            if app.TimeScopeCheckBox.Value
                if app.AbsoluteValueCheckBox.Value
                    time_scope(abs(data));
                else
                    time_scope(data);
                end
            end
            
            %% set up usrp
            radio = comm.SDRuTransmitter(Platform = "N200/N210/USRP2", ...
                IPAddress =  '192.168.10.3', ...
                CenterFrequency = 13.56*1e6, ...
                Gain = 0, ...
                MasterClockRate = 100*1e6, ...
                InterpolationFactor = InterpolationFactor, ...
                EnableBurstMode=true, ...
                NumFramesInBurst=100);

            app.TransmittingLabel.Text = "Transmitting";
            app.TransmittingLabel.FontColor = [0,1,0];
            app.TransmitButton.Enable = false;
            drawnow();

            %%Transmits data
            for i = 1:1:100
                radio(data);
            end
            release(radio);
            app.TransmittingLabel.Text = "Not Transmitting";
            app.TransmittingLabel.FontColor = [1,0,0];
            app.TransmitButton.Enable = true;
            drawnow();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TransmitButton
        function TransmitButtonPushed(app, event)
            %%
            % Transmits data from Hex String Field
            % at chosen sample rate
            app.InvalidHexLabel.Visible = false;
            
            %Checks whether to send a command or hex string
            if app.CommandButton.Value
                hexString = app.CommandDropDown.Value;
            else
                hexString = app.HexStringField.Value;
            end
            %Check for a valid hex string and then transmit
            try
                usrpTransmit(app, hexString);
            catch ME
                %Check if Hex String is valid characters
                if strcmp(ME.identifier, 'daq:general:invalidHexCharacters') || strcmp(ME.identifier, 'daq:general:needsHexNumber')
                    % Handle error
                    app.TransmittingLabel.Text = "Not Transmitting";
                    app.TransmittingLabel.FontColor = [1,0,0];
                    app.InvalidHexLabel.Visible = true;
                else
                    % If it's a different error, rethrow
                    app.TransmittingLabel.Text = "Not Transmitting";
                    app.TransmittingLabel.FontColor = [1,0,0];
                    drawnow();
                    rethrow(ME);
                end
            end
        end

        % Callback function: not associated with a component
        function OpenButtonPushed(app, event)
            app.ContextMenu.ContextMenuOpeningFcn
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransmitButton = uibutton(app.UIFigure, 'push');
            app.TransmitButton.ButtonPushedFcn = createCallbackFcn(app, @TransmitButtonPushed, true);
            app.TransmitButton.Text = 'Transmit';
            app.EnterHexStringLabel = uilabel(app.UIFigure);
            app.EnterHexStringLabel.Text = 'Enter Hex String:';
            app.HexStringField = uieditfield(app.UIFigure, 'text');
            app.HexStringField.Value = '9040007360';
            app.EnterSampleRateinMHzEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSampleRateinMHzEditFieldLabel.Text = 'Enter Sample Rate (in MHz):';
            app.SampleRateField = uieditfield(app.UIFigure, 'numeric');
            app.SampleRateField.ValueDisplayFormat = '%.3f';
            app.SampleRateField.Value = 12.5;
            app.InvalidHexLabel = uilabel(app.UIFigure);
            app.InvalidHexLabel.Visible = 'off';
            app.InvalidHexLabel.Text = 'Invalid Hex String';
            app.ASKDropDownLabel = uilabel(app.UIFigure);
            app.ASKDropDownLabel.Text = 'ASK %:';
            app.ASKDropDown = uidropdown(app.UIFigure);
            app.ASKDropDown.Items = {'10%', '15%', '20%', '25%', '30%', '100%'};
            app.ASKDropDown.ItemsData = [0.1 0.15 0.2 0.25 0.3 1];
            app.ASKDropDown.Value = 1;
            app.CommandLabel = uilabel(app.UIFigure);
            app.CommandLabel.Text = 'Command:';
            app.CommandDropDown = uidropdown(app.UIFigure);
            app.CommandDropDown.Items = {'Inventory', 'Reset to Ready', 'System Information'};
            app.CommandDropDown.ItemsData = {'9040007360', '80987360', '80E87360'};
            app.CommandDropDown.Value = '9040007360';
            app.ChooseTransmitTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseTransmitTypeButtonGroup.Title = 'Choose Transmit Type:';
            app.CommandButton = uiradiobutton(app.ChooseTransmitTypeButtonGroup);
            app.CommandButton.Text = 'Command';
            app.CommandButton.Value = true;
            app.HexStringButton = uiradiobutton(app.ChooseTransmitTypeButtonGroup);
            app.HexStringButton.Text = 'Hex String';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status:';
            app.TransmittingLabel = uilabel(app.UIFigure);
            app.TransmittingLabel.Text = 'Not Transmitting';
            app.TimeScopeCheckBox = uicheckbox(app.UIFigure);
            app.TimeScopeCheckBox.Text = 'Show Time Scope';
            app.TimeScopeCheckBox.Value = true;
            app.SineWaveFrequencyHzLabel = uilabel(app.UIFigure);
            app.SineWaveFrequencyHzLabel.Text = 'Sine Wave Frequency (Hz):';
            app.SineWaveFrequency = uieditfield(app.UIFigure, 'numeric');
            app.SineWaveFrequency.Value = 166.66;
            app.DataOptionsLabel = uilabel(app.UIFigure);
            app.DataOptionsLabel.Text = 'Data Options';
            app.SineWaveComplexCheckBox = uicheckbox(app.UIFigure);
            app.SineWaveComplexCheckBox.Text = '';
            app.SineWaveComplexCheckBox.Value = true;
            app.SineWaveComplexLabel = uilabel(app.UIFigure);
            app.SineWaveComplexLabel.Text = 'Sine Wave Complex:';
            app.TimeScopeOptionsLabel = uilabel(app.UIFigure);
            app.TimeScopeOptionsLabel.Text = 'Time Scope Options';
            app.AbsoluteValueCheckBox = uicheckbox(app.UIFigure);
            app.AbsoluteValueCheckBox.Text = 'Absolute Value';
            app.AbsoluteValueCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end